classdef Dampers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TravelCheckBox           matlab.ui.control.CheckBox
        SpeedCheckBox            matlab.ui.control.CheckBox
        SpeedHistogramCheckBox   matlab.ui.control.CheckBox
        AxesFL                   matlab.ui.control.UIAxes
        AxesFR                   matlab.ui.control.UIAxes
        AxesRL                   matlab.ui.control.UIAxes
        AxesRR                   matlab.ui.control.UIAxes
        SuspensionSensorsLabel   matlab.ui.control.Label
        FilterorderSpinnerLabel  matlab.ui.control.Label
        FilterorderSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        CallingApp %Datavisu
        XFLf % filtered data
        XFRf
        XRLf
        XRRf
        Filter_order
        VFL
        VFR
        VRL
        VRR
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.UIFigure.Name = 'Dampers';
            app.CallingApp = Mainapp;
            app.TravelCheckBox.Value = 1;
            app.SpeedCheckBox.Value = 0;
            app.SpeedHistogramCheckBox.Value = 0;
            %Data filter : Moving average
            app.Filter_order = 50; %app.FilterorderSpinner.Value;
            %[a,b] = butter(app.Filter_order,(250/2),'low');
            a = 1;
            b = ones(1,app.Filter_order)*1/app.Filter_order;
            app.XFLf = filter(b,a,app.CallingApp.XFL);
            app.XFRf = filter(b,a,app.CallingApp.XFR);
            app.XRLf = filter(b,a,app.CallingApp.XRL);
            app.XRRf = filter(b,a,app.CallingApp.XRR);
            %on enlève les premiers éléments
            app.XFLf = app.XFLf(app.Filter_order:end);
            app.XFRf = app.XFRf(app.Filter_order:end);
            app.XRLf = app.XRLf(app.Filter_order:end);
            app.XRRf = app.XRRf(app.Filter_order:end);
            %%% On calcule les vitesses avec une méthode d'ordre 1
            app.VFL = [];
            app.VFR = [];
            app.VRL = [];
            app.VRR = [];
            %Attention les vecteurs de vitesse sont plus petit (une valeur en moins)
            Te=1/50;
            for k=1:length(app.XFLf)-1
                app.VFL = [app.VFL (app.XFLf(k+1)-app.XFLf(k))/Te];
                app.VFR = [app.VFR (app.XFRf(k+1)-app.XFRf(k))/Te];
                app.VRL = [app.VRL (app.XRLf(k+1)-app.XRLf(k))/Te];
                app.VRR = [app.VRR (app.XRRf(k+1)-app.XRRf(k))/Te];
            end 
            plot(app.AxesFL,app.CallingApp.Tacc(app.Filter_order:end),app.XFLf)
            plot(app.AxesFR,app.CallingApp.Tacc(app.Filter_order:end),app.XFRf)
            plot(app.AxesRL,app.CallingApp.Tacc(app.Filter_order:end),app.XRLf)
            plot(app.AxesRR,app.CallingApp.Tacc(app.Filter_order:end),app.XRRf)
        end

        % Value changed function: SpeedCheckBox
        function SpeedCheckBoxValueChanged(app, event)

            app.TravelCheckBox.Value = 0 ;
            app.SpeedHistogramCheckBox.Value = 0 ;
            plot(app.AxesFL,app.CallingApp.Tacc(app.Filter_order:end-1),app.VFL)
            plot(app.AxesFR,app.CallingApp.Tacc(app.Filter_order:end-1),app.VFR)
            plot(app.AxesRL,app.CallingApp.Tacc(app.Filter_order:end-1),app.VRL)
            plot(app.AxesRR,app.CallingApp.Tacc(app.Filter_order:end-1),app.VRR)
        end

        % Value changed function: SpeedHistogramCheckBox
        function SpeedHistogramCheckBoxValueChanged(app, event)
            app.TravelCheckBox.Value = 0 ;
            app.SpeedCheckBox.Value = 0 ;
            histogram(app.AxesFL,app.VFL)
            histogram(app.AxesFR,app.VFR)
            histogram(app.AxesRL,app.VRL)
            histogram(app.AxesRR,app.VRR)
            
        end

        % Value changed function: TravelCheckBox
        function TravelCheckBoxValueChanged(app, event)
            app.SpeedCheckBox.Value = 0 ;
            app.SpeedHistogramCheckBox.Value = 0 ;
            plot(app.AxesFL,app.CallingApp.Tacc(app.Filter_order:end),app.XFLf)
            plot(app.AxesFR,app.CallingApp.Tacc(app.Filter_order:end),app.XFRf)
            plot(app.AxesRL,app.CallingApp.Tacc(app.Filter_order:end),app.XRLf)
            plot(app.AxesRR,app.CallingApp.Tacc(app.Filter_order:end),app.XRRf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TravelCheckBox = uicheckbox(app.UIFigure);
            app.TravelCheckBox.ValueChangedFcn = createCallbackFcn(app, @TravelCheckBoxValueChanged, true);
            app.TravelCheckBox.Text = 'Travel';
            app.SpeedCheckBox = uicheckbox(app.UIFigure);
            app.SpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedCheckBoxValueChanged, true);
            app.SpeedCheckBox.Text = 'Speed';
            app.SpeedHistogramCheckBox = uicheckbox(app.UIFigure);
            app.SpeedHistogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedHistogramCheckBoxValueChanged, true);
            app.SpeedHistogramCheckBox.Text = 'Speed Histogram';
            app.AxesFL = uiaxes(app.UIFigure);
            title(app.AxesFL, 'FL')
            xlabel(app.AxesFL, 'X')
            ylabel(app.AxesFL, 'Y')
            app.AxesFR = uiaxes(app.UIFigure);
            title(app.AxesFR, 'FR')
            xlabel(app.AxesFR, 'X')
            ylabel(app.AxesFR, 'Y')
            app.AxesRL = uiaxes(app.UIFigure);
            title(app.AxesRL, 'RL')
            xlabel(app.AxesRL, 'X')
            ylabel(app.AxesRL, 'Y')
            app.AxesRR = uiaxes(app.UIFigure);
            title(app.AxesRR, 'RR')
            xlabel(app.AxesRR, 'X')
            ylabel(app.AxesRR, 'Y')
            app.SuspensionSensorsLabel = uilabel(app.UIFigure);
            app.SuspensionSensorsLabel.Text = 'Suspension Sensors';
            app.FilterorderSpinnerLabel = uilabel(app.UIFigure);
            app.FilterorderSpinnerLabel.Text = 'Filter order';
            app.FilterorderSpinner = uispinner(app.UIFigure);
            app.FilterorderSpinner.Limits = [0 6];
            app.FilterorderSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dampers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end