classdef XY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trackplot       matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
        YDropDownLabel  matlab.ui.control.Label
        YDropDown       matlab.ui.control.DropDown
        XYLabel         matlab.ui.control.Label
        XDropDownLabel  matlab.ui.control.Label
        XDropDown       matlab.ui.control.DropDown
        PlotButton      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Datavisu
        Ydata %Données à afficher en Y
        Xdata %Données à afficher en X
        YLabel %Nom des données en Y
        XLabel %Nom des données en X
        data_struct=log2struct('test.log');

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.YDropDown.Items = {app.data_struct.Name};
            app.XDropDown.Items = {app.data_struct.Name};
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            value = app.YDropDown.Value;
            data_index = get_data_index(app.data_struct, value);
            app.Ydata = app.data_struct(data_index).Data;
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            value = app.XDropDown.Value;
            data_index = get_data_index(app.data_struct, value);
            app.Xdata = app.data_struct(data_index).Data;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot(app.UIAxes, app.Xdata, app.Ydata);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trackplot.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {620, 620};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {31, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trackplot = uifigure('Visible', 'off');
            app.Trackplot.Name = 'UI Figure';
            app.Trackplot.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trackplot);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'X-Y plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YDropDownLabel = uilabel(app.RightPanel);
            app.YDropDownLabel.Text = 'Y :';
            app.YDropDown = uidropdown(app.RightPanel);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.XYLabel = uilabel(app.RightPanel);
            app.XYLabel.Text = 'X-Y';
            app.XDropDownLabel = uilabel(app.RightPanel);
            app.XDropDownLabel.Text = 'X :';
            app.XDropDown = uidropdown(app.RightPanel);
            app.XDropDown.Items = {};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = {};
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.Trackplot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XY(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trackplot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trackplot)
        end
    end
end